classdef CrossGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CrossDialog               matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenFile                  matlab.ui.container.Menu
        SaveFile                  matlab.ui.container.Menu
        Exit                      matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        ControlRuler              matlab.ui.container.Panel
        Restart                   matlab.ui.control.Button
        Step                      matlab.ui.control.Button
        GoThru                    matlab.ui.control.Button
        Presition                 matlab.ui.control.DropDown
        ApoioIncialDropDownLabel  matlab.ui.control.Label
        SupInit                   matlab.ui.control.DropDown
        DeleteSup                 matlab.ui.control.StateButton
        ApoioFinalDropDownLabel   matlab.ui.control.Label
        SupEnd                    matlab.ui.control.DropDown
        InsertSup                 matlab.ui.control.StateButton
        ModelPanel                matlab.ui.container.Panel
        ax_model                  matlab.ui.control.UIAxes
        DeformedPanel             matlab.ui.container.Panel
        ax_deformed               matlab.ui.control.UIAxes
        MomentPanel               matlab.ui.container.Panel
        ax_moment                 matlab.ui.control.UIAxes
        SolutionPanel             matlab.ui.container.Panel
        ax_iterativeSolution      matlab.ui.control.UIAxes
        MessageText               matlab.ui.control.Label
    end

    properties (Access = public)
        control       CrossControl
    end
            
    methods (Access = private)    
        % Called when user attempts to close dialog
        function deleteCallback(app)
            choice = questdlg('Are you sure you want to exit?',...
            'Exit','Exit','Cancel','Cancel');
            switch choice
                case 'Cancel'
                    return
            end
            app.delete();
        end

        %------------------------------------------------------------------
        % Called when axes graphical component is resized
        function Canvas_SizeChangedFcn(app,event)
            if ~isempty(app.control)
                app.control.resizeCanvas(event.Source);
            end
        end
    end
    
    methods (Access = public)
        
        function updateTextMessage(app)
            if app.control.solver.moreStepsToGo()
                app.MessageText.Text = 'Moments are not balanced yet.';
            else
                app.MessageText.Text = 'Cross iterative solution completed.';
            end            
        end
        
        function setTextMessage(app,msg)
            app.MessageText.Text = msg;
        end


        function resetInsertSup(app)
            if app.InsertSup.Value
                app.InsertSup.Value = false;
                app.control.insertSupMode(false);
            end            
        end
        
        function resetDeleteSup(app)
            if app.DeleteSup.Value
                app.DeleteSup.Value = false;
                app.control.deleteSupMode(false);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create and initialize cross control object
            app.control = CrossControl(app);
            app.control.startup(app.CrossDialog,app.ax_model,app.ax_deformed,app.ax_moment,app.ax_iterativeSolution);
            
            % Set close app callback function
            app.CrossDialog.CloseRequestFcn = app.createCallbackFcn(@deleteCallback, false);

            % Turn off toolbar of all axes
            app.ax_model.Toolbar.Visible = 'off';
            app.ax_model.Interactions = [];
            app.ax_deformed.Toolbar.Visible = 'off';
            app.ax_deformed.Interactions = [];
            app.ax_moment.Toolbar.Visible = 'off';
            app.ax_moment.Interactions = [];
            app.ax_iterativeSolution.Toolbar.Visible = 'off';
            app.ax_iterativeSolution.Interactions = [];
            
            % Setup change size function of all axes
            app.ax_model.SizeChangedFcn = createCallbackFcn(app, @Canvas_SizeChangedFcn, true);
            app.ax_deformed.SizeChangedFcn = createCallbackFcn(app, @Canvas_SizeChangedFcn, true);
            app.ax_moment.SizeChangedFcn = createCallbackFcn(app, @Canvas_SizeChangedFcn, true);
            app.ax_iterativeSolution.SizeChangedFcn = createCallbackFcn(app, @Canvas_SizeChangedFcn, true);
        end

        % Button pushed function: Restart
        function RestartButtonPushed(app, event)
            app.resetInsertSup();
            app.resetDeleteSup();
            app.control.restart();
            app.updateTextMessage();
        end

        % Button pushed function: Step
        function StepButtonPushed(app, event)
            app.resetInsertSup();
            app.resetDeleteSup();
            app.control.autoStep();
            app.updateTextMessage();
        end

        % Button pushed function: GoThru
        function GoThruButtonPushed(app, event)
            app.resetInsertSup();
            app.resetDeleteSup();
            app.control.goThru();
            app.updateTextMessage();
        end

        % Value changed function: InsertSup
        function InsertSupValueChanged(app, event)
            app.resetDeleteSup();
            value = app.InsertSup.Value;
            app.control.insertSupMode(value);
            app.MessageText.Text = 'Insert an internal support with mouse pointer.';
        end

        % Value changed function: DeleteSup
        function DeleteSupValueChanged(app, event)
            app.resetInsertSup();
            value = app.DeleteSup.Value;
            app.control.deleteSupMode(value);
            app.MessageText.Text = 'Select an internal support for deletion with mouse pointer.';
        end

        % Value changed function: Presition
        function PresitionValueChanged(app, event)
            app.resetInsertSup();
            app.resetDeleteSup();
            value = app.Presition.Value;
            if strcmp(value,'1 kNm')
                decplc = 0;
            elseif strcmp(value,'0.1 kNm')
                decplc = 1;
            else
                decplc = 2;
            end
            app.control.setPrecision(decplc);
            app.updateTextMessage();            
        end

        % Menu selected function: Exit
        function ExitMenuSelected(app, event)
            app.deleteCallback();
        end

        % Menu selected function: OpenFile
        function OpenFileMenuSelected(app, event)
            filterspec = {'*.crs'};
            [filename,pathname] = uigetfile(filterspec,'Cross Program - Input file');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                app.control.openFile(fullname);
            end
            app.updateTextMessage();
        end

        % Menu selected function: SaveFile
        function SaveFileMenuSelected(app, event)
            filterspec = {'*.crs'};
            [filename,pathname] = uiputfile(filterspec,'Cross Program - Output file');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                app.control.saveFile(fullname);
            end            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({ 'PUC-Rio - CIV2801 - Fundamentos de Computação Gráfica Aplicada - 2019.2'; ' '; ...
                      'Program to demonstrate the Cross process applied to a continous beam.' }, 'About Cross program');
        end

        % Value changed function: SupInit
        function SupInitValueChanged(app, event)
            app.resetInsertSup();
            app.resetDeleteSup();
            value = app.SupInit.Value;
            if strcmp(value,'Livre')
                supinit = 2;
            elseif strcmp(value,'Simples')
                supinit = 0;
            elseif strcmp(value,'Engastado')
                supinit = 1;
            end
            app.control.setSupInit(supinit);
            app.updateTextMessage(); 
        end

        % Value changed function: SupEnd
        function SupEndValueChanged(app, event)
            value = app.SupEnd.Value;
            app.resetInsertSup();
            app.resetDeleteSup();
            if strcmp(value,'Livre')
                supend = 2;
            elseif strcmp(value,'Simples')
                supend = 0;
            elseif strcmp(value,'Engastado')
                supend = 1;
            end
            app.control.setSupEnd(supend);
            app.updateTextMessage(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CrossDialog = uifigure('Visible', 'off');
            app.CrossDialog.Name = 'Cross process of continous beam';
            app.FileMenu = uimenu(app.CrossDialog);
            app.FileMenu.Text = 'File';
            app.OpenFile = uimenu(app.FileMenu);
            app.OpenFile.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFile.Text = 'Open';
            app.SaveFile = uimenu(app.FileMenu);
            app.SaveFile.MenuSelectedFcn = createCallbackFcn(app, @SaveFileMenuSelected, true);
            app.SaveFile.Text = 'Save as ...';
            app.Exit = uimenu(app.FileMenu);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.Exit.Text = 'Exit ...';
            app.AboutMenu = uimenu(app.CrossDialog);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ControlRuler = uipanel(app.CrossDialog);
            app.Restart = uibutton(app.ControlRuler, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.Restart.Icon = 'restart.gif';
            app.Restart.Tooltip = {'Initialize interative solution of Cross process'};
            app.Restart.Text = '';
            app.Step = uibutton(app.ControlRuler, 'push');
            app.Step.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.Step.Icon = 'step.gif';
            app.Step.Tooltip = {'Process one step of iterative solution '};
            app.Step.Text = '';
            app.GoThru = uibutton(app.ControlRuler, 'push');
            app.GoThru.ButtonPushedFcn = createCallbackFcn(app, @GoThruButtonPushed, true);
            app.GoThru.Icon = 'gothru.gif';
            app.GoThru.Tooltip = {'Go through: process all steps of iterative solution'};
            app.GoThru.Text = '';
            app.Presition = uidropdown(app.ControlRuler);
            app.Presition.Items = {'1 kNm', '0.1 kNm', '0.01 kNm'};
            app.Presition.ValueChangedFcn = createCallbackFcn(app, @PresitionValueChanged, true);
            app.Presition.Tooltip = {'Set bending moment precision of iterative solution'};
            app.Presition.Value = '0.1 kNm';
            app.ApoioIncialDropDownLabel = uilabel(app.ControlRuler);
            app.ApoioIncialDropDownLabel.Text = 'Apoio Incial';
            app.SupInit = uidropdown(app.ControlRuler);
            app.SupInit.Items = {'Livre', 'Simples', 'Engastado'};
            app.SupInit.ValueChangedFcn = createCallbackFcn(app, @SupInitValueChanged, true);
            app.SupInit.Value = 'Simples';
            app.DeleteSup = uibutton(app.ControlRuler, 'state');
            app.DeleteSup.ValueChangedFcn = createCallbackFcn(app, @DeleteSupValueChanged, true);
            app.DeleteSup.Tooltip = {'Delete internal support'};
            app.DeleteSup.Icon = 'supdelete.gif';
            app.DeleteSup.Text = '';
            app.ApoioFinalDropDownLabel = uilabel(app.ControlRuler);
            app.ApoioFinalDropDownLabel.Text = 'Apoio Final';
            app.SupEnd = uidropdown(app.ControlRuler);
            app.SupEnd.Items = {'Livre', 'Simples', 'Engastado'};
            app.SupEnd.ValueChangedFcn = createCallbackFcn(app, @SupEndValueChanged, true);
            app.SupEnd.Value = 'Engastado';
            app.InsertSup = uibutton(app.ControlRuler, 'state');
            app.InsertSup.ValueChangedFcn = createCallbackFcn(app, @InsertSupValueChanged, true);
            app.InsertSup.Tooltip = {'Insert internal support'};
            app.InsertSup.Icon = 'supinsert.gif';
            app.InsertSup.Text = '';
            app.ModelPanel = uipanel(app.CrossDialog);
            app.ModelPanel.Title = 'Continous beam model';
            app.ax_model = uiaxes(app.ModelPanel);
            title(app.ax_model, '')
            xlabel(app.ax_model, '')
            ylabel(app.ax_model, '')
            app.ax_model.XLim = [0 1];
            app.ax_model.YLim = [0 1];
            app.ax_model.ZLim = [0 1];
            app.ax_model.XTick = [];
            app.ax_model.YTick = [];
            app.DeformedPanel = uipanel(app.CrossDialog);
            app.DeformedPanel.Title = 'Deformed configuration';
            app.ax_deformed = uiaxes(app.DeformedPanel);
            title(app.ax_deformed, '')
            xlabel(app.ax_deformed, '')
            ylabel(app.ax_deformed, '')
            app.ax_deformed.XLim = [0 1];
            app.ax_deformed.YLim = [0 1];
            app.ax_deformed.ZLim = [0 1];
            app.ax_deformed.XTick = [];
            app.ax_deformed.YTick = [];
            app.MomentPanel = uipanel(app.CrossDialog);
            app.MomentPanel.Title = 'Bendind moment diagram';
            app.ax_moment = uiaxes(app.MomentPanel);
            title(app.ax_moment, '')
            xlabel(app.ax_moment, '')
            ylabel(app.ax_moment, '')
            app.ax_moment.XLim = [0 1];
            app.ax_moment.YLim = [0 1];
            app.ax_moment.ZLim = [0 1];
            app.ax_moment.XTick = [];
            app.ax_moment.YTick = [];
            app.SolutionPanel = uipanel(app.CrossDialog);
            app.SolutionPanel.Title = 'Iterative Cross solution';
            app.ax_iterativeSolution = uiaxes(app.SolutionPanel);
            title(app.ax_iterativeSolution, '')
            xlabel(app.ax_iterativeSolution, '')
            ylabel(app.ax_iterativeSolution, '')
            app.ax_iterativeSolution.XLim = [0 1];
            app.ax_iterativeSolution.YLim = [0 1];
            app.ax_iterativeSolution.ZLim = [0 1];
            app.ax_iterativeSolution.XTick = [];
            app.ax_iterativeSolution.YTick = [];
            app.MessageText = uilabel(app.CrossDialog);
            app.MessageText.Text = 'Moments are not balanced yet.';
            % Show the figure after all components are created
            app.CrossDialog.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrossGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CrossDialog)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CrossDialog)
        end
    end
end